classdef EDFA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PumpWavelengthEditFieldLabel    matlab.ui.control.Label
        PumpWavelengthEditField         matlab.ui.control.NumericEditField
        SignalWavelengthEditFieldLabel  matlab.ui.control.Label
        SignalWavelengthEditField       matlab.ui.control.NumericEditField
        GainEditFieldLabel              matlab.ui.control.Label
        GainEditField                   matlab.ui.control.NumericEditField
        PumpPowerEditFieldLabel         matlab.ui.control.Label
        PumpPowerEditField              matlab.ui.control.NumericEditField
        RunButton                       matlab.ui.control.Button
        MaxInputPowerEditFieldLabel     matlab.ui.control.Label
        MaxInputPowerEditField          matlab.ui.control.NumericEditField
        OutputPowerEditFieldLabel       matlab.ui.control.Label
        OutputPowerEditField            matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            p_w=app.PumpWavelengthEditField.Value;
            s_w=app.SignalWavelengthEditField.Value;
            ga=app.GainEditField.Value;
            p_p=app.PumpPowerEditField.Value;
            a=10^(ga/10)-1;
            m_ip=((p_w/s_w)*(p_p)/a);
            w=(p_w/s_w);
            m_op=m_ip+w*p_p;
            app.MaxInputPowerEditField.Value = m_ip;
            app.OutputPowerEditField.Value = m_op;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.PumpWavelengthEditFieldLabel = uilabel(app.UIFigure);
            app.PumpWavelengthEditFieldLabel.Text = 'Pump Wavelength';
            app.PumpWavelengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.SignalWavelengthEditFieldLabel = uilabel(app.UIFigure);
            app.SignalWavelengthEditFieldLabel.Text = 'Signal Wavelength';
            app.SignalWavelengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.GainEditFieldLabel = uilabel(app.UIFigure);
            app.GainEditFieldLabel.Text = 'Gain';
            app.GainEditField = uieditfield(app.UIFigure, 'numeric');
            app.PumpPowerEditFieldLabel = uilabel(app.UIFigure);
            app.PumpPowerEditFieldLabel.Text = 'Pump Power';
            app.PumpPowerEditField = uieditfield(app.UIFigure, 'numeric');
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.MaxInputPowerEditFieldLabel = uilabel(app.UIFigure);
            app.MaxInputPowerEditFieldLabel.Text = 'Max Input Power';
            app.MaxInputPowerEditField = uieditfield(app.UIFigure, 'numeric');
            app.OutputPowerEditFieldLabel = uilabel(app.UIFigure);
            app.OutputPowerEditFieldLabel.Text = 'Output Power';
            app.OutputPowerEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EDFA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end