classdef Fiber_dispersion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FiberDispersionLabel           matlab.ui.control.Label
        ZeroDispersionWavelengthEditFieldLabel  matlab.ui.control.Label
        ZeroDispersionWavelengthEditField  matlab.ui.control.NumericEditField
        DispersionSlopepsnm2kmEditFieldLabel  matlab.ui.control.Label
        DispersionSlopepsnm2kmEditField  matlab.ui.control.NumericEditField
        LengthoffibreEditFieldLabel    matlab.ui.control.Label
        LengthoffibreEditField         matlab.ui.control.NumericEditField
        SpectralWidthofTxEditFieldLabel  matlab.ui.control.Label
        SpectralWidthofTxEditField     matlab.ui.control.NumericEditField
        RunButton                      matlab.ui.control.Button
        PulseSpreadEditFieldLabel      matlab.ui.control.Label
        PulseSpreadEditField           matlab.ui.control.NumericEditField
        ChromaticDispersionFactorEditFieldLabel  matlab.ui.control.Label
        ChromaticDispersionFactorEditField  matlab.ui.control.NumericEditField
        DispersionWavelengthEditFieldLabel  matlab.ui.control.Label
        DispersionWavelengthEditField  matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            w=app.ZeroDispersionWavelengthEditField.Value ;
            s=app.DispersionSlopepsnm2kmEditField.Value ;
            l= app.LengthoffibreEditField.Value ;
            s= app.SpectralWidthofTxEditField.Value ;
            d= app.DispersionWavelengthEditField.Value ;
           c = d*s/4;
           e= c*(1-(w/d)^4);
           app.ChromaticDispersionFactorEditField.Value = e ;
           p = l*e*s ;
           app.PulseSpreadEditField.Value = p ;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FiberDispersionLabel = uilabel(app.UIFigure);
            app.FiberDispersionLabel.Text = {'Fiber Dispersion'; ''};
            app.ZeroDispersionWavelengthEditFieldLabel = uilabel(app.UIFigure);
            app.ZeroDispersionWavelengthEditFieldLabel.Text = {'Zero Dispersion Wavelength'; ''};
            app.ZeroDispersionWavelengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.DispersionSlopepsnm2kmEditFieldLabel = uilabel(app.UIFigure);
            app.DispersionSlopepsnm2kmEditFieldLabel.Text = {'Dispersion Slope(ps/nm^2.km)'; ''};
            app.DispersionSlopepsnm2kmEditField = uieditfield(app.UIFigure, 'numeric');
            app.LengthoffibreEditFieldLabel = uilabel(app.UIFigure);
            app.LengthoffibreEditFieldLabel.Text = {'Length of fibre'; ''};
            app.LengthoffibreEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpectralWidthofTxEditFieldLabel = uilabel(app.UIFigure);
            app.SpectralWidthofTxEditFieldLabel.Text = {'Spectral Width of  Tx'; ''};
            app.SpectralWidthofTxEditField = uieditfield(app.UIFigure, 'numeric');
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = {'Run'; ''};
            app.PulseSpreadEditFieldLabel = uilabel(app.UIFigure);
            app.PulseSpreadEditFieldLabel.Text = {'Pulse Spread'; ''};
            app.PulseSpreadEditField = uieditfield(app.UIFigure, 'numeric');
            app.ChromaticDispersionFactorEditFieldLabel = uilabel(app.UIFigure);
            app.ChromaticDispersionFactorEditFieldLabel.Text = {'Chromatic Dispersion Factor'; ''};
            app.ChromaticDispersionFactorEditField = uieditfield(app.UIFigure, 'numeric');
            app.DispersionWavelengthEditFieldLabel = uilabel(app.UIFigure);
            app.DispersionWavelengthEditFieldLabel.Text = {'Dispersion Wavelength'; ''};
            app.DispersionWavelengthEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Fiber_dispersion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end