classdef LED_parameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RadiativetimeEditFieldLabel     matlab.ui.control.Label
        RadiativetimeEditField          matlab.ui.control.NumericEditField
        NonRadiativetimeEditFieldLabel  matlab.ui.control.Label
        NonRadiativetimeEditField       matlab.ui.control.NumericEditField
        DrivecurrentEditFieldLabel      matlab.ui.control.Label
        DrivecurrentEditField           matlab.ui.control.NumericEditField
        WavelengthEditFieldLabel        matlab.ui.control.Label
        WavelengthEditField             matlab.ui.control.NumericEditField
        LEDParametersLabel              matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        BulkrecombinationtimensEditFieldLabel  matlab.ui.control.Label
        BulkrecombinationtimensEditField  matlab.ui.control.NumericEditField
        InternalquantumefficiencyEditFieldLabel  matlab.ui.control.Label
        InternalquantumefficiencyEditField  matlab.ui.control.NumericEditField
        InternalpowerlevelEditFieldLabel  matlab.ui.control.Label
        InternalpowerlevelEditField     matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            Rt = app.RadiativetimeEditField.Value ;
            Nrt = app.NonRadiativetimeEditField.Value ;
            Dc = app.DrivecurrentEditField.Value ;
            wl = app.WavelengthEditField.Value ;
            h = 6.6256*10^-34 ;
            l = 3*10^8 ;
            ey = 1.602*10^-19 ;
            lda = 1.31*10^-6 ;
            blk_rec = (Rt+Nrt)/(Rt*Nrt) ;
            Iqf = blk_rec/Rt ;
            Ipl = Iqf*((h*Dc*l)/(ey*lda));
            app.BulkrecombinationtimensEditField.Value = blk_rec ;
            app.InternalquantumefficiencyEditField.Value = Iqf ;
            app.InternalpowerlevelEditField.Value = Ipl ;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RadiativetimeEditFieldLabel = uilabel(app.UIFigure);
            app.RadiativetimeEditFieldLabel.Text = 'Radiative time';
            app.RadiativetimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.NonRadiativetimeEditFieldLabel = uilabel(app.UIFigure);
            app.NonRadiativetimeEditFieldLabel.Text = 'Non Radiative time';
            app.NonRadiativetimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.DrivecurrentEditFieldLabel = uilabel(app.UIFigure);
            app.DrivecurrentEditFieldLabel.Text = 'Drive current';
            app.DrivecurrentEditField = uieditfield(app.UIFigure, 'numeric');
            app.WavelengthEditFieldLabel = uilabel(app.UIFigure);
            app.WavelengthEditFieldLabel.Text = 'Wavelength';
            app.WavelengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.LEDParametersLabel = uilabel(app.UIFigure);
            app.LEDParametersLabel.Text = 'LED Parameters';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.BulkrecombinationtimensEditFieldLabel = uilabel(app.UIFigure);
            app.BulkrecombinationtimensEditFieldLabel.Text = 'Bulk recombination time(ns)';
            app.BulkrecombinationtimensEditField = uieditfield(app.UIFigure, 'numeric');
            app.InternalquantumefficiencyEditFieldLabel = uilabel(app.UIFigure);
            app.InternalquantumefficiencyEditFieldLabel.Text = 'Internal quantum efficiency ';
            app.InternalquantumefficiencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.InternalpowerlevelEditFieldLabel = uilabel(app.UIFigure);
            app.InternalpowerlevelEditFieldLabel.Text = 'Internal power level';
            app.InternalpowerlevelEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LED_parameters

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end