classdef Laser_threshold < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GainThresholdforLaserDiodeLabel  matlab.ui.control.Label
        LengthofLaserLightEditFieldLabel  matlab.ui.control.Label
        LengthofLaserLightEditField    matlab.ui.control.NumericEditField
        ResistancevalueEditFieldLabel  matlab.ui.control.Label
        ResistancevalueEditField       matlab.ui.control.NumericEditField
        UIAxes                         matlab.ui.control.UIAxes
        TotalAttenuationLossEditFieldLabel  matlab.ui.control.Label
        TotalAttenuationLossEditField  matlab.ui.control.NumericEditField
        Button                         matlab.ui.control.Button
        GainThresholdEditFieldLabel    matlab.ui.control.Label
        GainThresholdEditField         matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            l=app.LengthofLaserLightEditField.Value ;
            r= app.ResistancevalueEditField.Value ;
            t= app.TotalAttenuationLossEditField.Value ;
            g =  t + 1/2*(l)*log(1/r^2);
            app.GainThresholdEditField.Value = g ;
            plot(app.UIAxes,l,g,"Marker","pentagram")
            app.UIAxes.XLim = [0 l];
            app.UIAxes.YLim = [-inf inf];
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GainThresholdforLaserDiodeLabel = uilabel(app.UIFigure);
            app.GainThresholdforLaserDiodeLabel.Text = {'Gain Threshold for Laser Diode'; ''};
            app.LengthofLaserLightEditFieldLabel = uilabel(app.UIFigure);
            app.LengthofLaserLightEditFieldLabel.Text = {'Length of Laser Light'; ''};
            app.LengthofLaserLightEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResistancevalueEditFieldLabel = uilabel(app.UIFigure);
            app.ResistancevalueEditFieldLabel.Text = {'Resistance value'; ''};
            app.ResistancevalueEditField = uieditfield(app.UIFigure, 'numeric');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Lasing threshold')
            xlabel(app.UIAxes, {'Length of Laser light'; ''})
            ylabel(app.UIAxes, {'Gain Threshold '; ''})
            app.TotalAttenuationLossEditFieldLabel = uilabel(app.UIFigure);
            app.TotalAttenuationLossEditFieldLabel.Text = {'Total Attenuation Loss'; ''};
            app.TotalAttenuationLossEditField = uieditfield(app.UIFigure, 'numeric');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.GainThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.GainThresholdEditFieldLabel.Text = {'Gain Threshold'; ''};
            app.GainThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Laser_threshold

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end