classdef coupler < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Port0EditFieldLabel           matlab.ui.control.Label
        Port0EditField                matlab.ui.control.NumericEditField
        Port1EditFieldLabel           matlab.ui.control.Label
        Port1EditField                matlab.ui.control.NumericEditField
        Port2EditFieldLabel           matlab.ui.control.Label
        Port2EditField                matlab.ui.control.NumericEditField
        Port3EditFieldLabel           matlab.ui.control.Label
        Port3EditField                matlab.ui.control.NumericEditField
        CouplingRatioEditFieldLabel   matlab.ui.control.Label
        CouplingRatioEditField        matlab.ui.control.NumericEditField
        RunButton                     matlab.ui.control.Button
        ExcesslossEditFieldLabel      matlab.ui.control.Label
        ExcesslossEditField           matlab.ui.control.NumericEditField
        Insertionloss0EditFieldLabel  matlab.ui.control.Label
        Insertionloss0EditField       matlab.ui.control.NumericEditField
        Insertionloss1EditFieldLabel  matlab.ui.control.Label
        Insertionloss1EditField       matlab.ui.control.NumericEditField
        ReturnlossEditFieldLabel      matlab.ui.control.Label
        ReturnlossEditField           matlab.ui.control.NumericEditField
        CouplersLabel                 matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            p_0=app.Port0EditField.Value;
            p_1=app.Port1EditField.Value;
            p_2=app.Port2EditField.Value;
            p_3=app.Port3EditField.Value;
            c_r=(p_2/(p_1+p_2)*100);
            e_l=(10*log10(p_0/p_1+p_2));
            i_l1=(10*log10(p_0/p_1));
            i_l2=(10*log10(p_0/p_2));
            r_l=(10*log10(p_3/p_0));
            app.CouplingRatioEditField.Value = c_r;
            app.ExcesslossEditField.Value = e_l;
            app.Insertionloss0EditField.Value = i_l1;
            app.Insertionloss1EditField.Value = i_l2;
            app.ReturnlossEditField.Value = r_l;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Port0EditFieldLabel = uilabel(app.UIFigure);
            app.Port0EditFieldLabel.Text = 'Port 0';
            app.Port0EditField = uieditfield(app.UIFigure, 'numeric');
            app.Port1EditFieldLabel = uilabel(app.UIFigure);
            app.Port1EditFieldLabel.Text = 'Port 1';
            app.Port1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Port2EditFieldLabel = uilabel(app.UIFigure);
            app.Port2EditFieldLabel.Text = 'Port 2 ';
            app.Port2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Port3EditFieldLabel = uilabel(app.UIFigure);
            app.Port3EditFieldLabel.Text = 'Port 3';
            app.Port3EditField = uieditfield(app.UIFigure, 'numeric');
            app.CouplingRatioEditFieldLabel = uilabel(app.UIFigure);
            app.CouplingRatioEditFieldLabel.Text = 'Coupling Ratio';
            app.CouplingRatioEditField = uieditfield(app.UIFigure, 'numeric');
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ExcesslossEditFieldLabel = uilabel(app.UIFigure);
            app.ExcesslossEditFieldLabel.Text = 'Excess loss';
            app.ExcesslossEditField = uieditfield(app.UIFigure, 'numeric');
            app.Insertionloss0EditFieldLabel = uilabel(app.UIFigure);
            app.Insertionloss0EditFieldLabel.Text = 'Insertion loss0';
            app.Insertionloss0EditField = uieditfield(app.UIFigure, 'numeric');
            app.Insertionloss1EditFieldLabel = uilabel(app.UIFigure);
            app.Insertionloss1EditFieldLabel.Text = 'Insertion loss1';
            app.Insertionloss1EditField = uieditfield(app.UIFigure, 'numeric');
            app.ReturnlossEditFieldLabel = uilabel(app.UIFigure);
            app.ReturnlossEditFieldLabel.Text = 'Return loss';
            app.ReturnlossEditField = uieditfield(app.UIFigure, 'numeric');
            app.CouplersLabel = uilabel(app.UIFigure);
            app.CouplersLabel.Text = 'Couplers';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = coupler

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end