classdef link_budget_design < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        LinkPowerBudgetLabel          matlab.ui.control.Label
        NoofconnectorsEditFieldLabel  matlab.ui.control.Label
        NoofconnectorsEditField       matlab.ui.control.NumericEditField
        NoofSplicesEditFieldLabel     matlab.ui.control.Label
        NoofSplicesEditField          matlab.ui.control.NumericEditField
        DatarateEditFieldLabel        matlab.ui.control.Label
        DatarateEditField             matlab.ui.control.NumericEditField
        LengthoffibreEditFieldLabel   matlab.ui.control.Label
        LengthoffibreEditField        matlab.ui.control.NumericEditField
        InputTxPowerEditFieldLabel    matlab.ui.control.Label
        InputTxPowerEditField         matlab.ui.control.NumericEditField
        OperatingWavelengthEditFieldLabel  matlab.ui.control.Label
        OperatingWavelengthEditField  matlab.ui.control.NumericEditField
        RunButton                     matlab.ui.control.Button
        OutputRxPowerEditFieldLabel   matlab.ui.control.Label
        OutputRxPowerEditField        matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            s = app.NoofSplicesEditField.Value;
            c= app.NoofconnectorsEditField.Value;
            l= app.LengthoffibreEditField.Value ;
            i= app.InputTxPowerEditField.Value ;
            d= app.DatarateEditField.Value ;
            o= app.OperatingWavelengthEditField.Value;
            a = s*0.2 + l*0.5 + c*1 ;
            t = i-a ;
            app.OutputRxPowerEditField.Value= t
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.LinkPowerBudgetLabel = uilabel(app.UIFigure);
            app.LinkPowerBudgetLabel.Text = {'Link Power Budget'; ''};
            app.NoofconnectorsEditFieldLabel = uilabel(app.UIFigure);
            app.NoofconnectorsEditFieldLabel.Text = {'No of connectors'; ''};
            app.NoofconnectorsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoofSplicesEditFieldLabel = uilabel(app.UIFigure);
            app.NoofSplicesEditFieldLabel.Text = 'No of Splices';
            app.NoofSplicesEditField = uieditfield(app.UIFigure, 'numeric');
            app.DatarateEditFieldLabel = uilabel(app.UIFigure);
            app.DatarateEditFieldLabel.Text = {'Data rate'; ''};
            app.DatarateEditField = uieditfield(app.UIFigure, 'numeric');
            app.LengthoffibreEditFieldLabel = uilabel(app.UIFigure);
            app.LengthoffibreEditFieldLabel.Text = {'Length of fibre'; ''};
            app.LengthoffibreEditField = uieditfield(app.UIFigure, 'numeric');
            app.InputTxPowerEditFieldLabel = uilabel(app.UIFigure);
            app.InputTxPowerEditFieldLabel.Text = {'Input Tx Power'; ''};
            app.InputTxPowerEditField = uieditfield(app.UIFigure, 'numeric');
            app.OperatingWavelengthEditFieldLabel = uilabel(app.UIFigure);
            app.OperatingWavelengthEditFieldLabel.Text = {'Operating Wavelength'; ''};
            app.OperatingWavelengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.OutputRxPowerEditFieldLabel = uilabel(app.UIFigure);
            app.OutputRxPowerEditFieldLabel.Text = 'Output Rx Power';
            app.OutputRxPowerEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = link_budget_design

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end