classdef optical_receiver_design < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        OpticalReceiverDesignLabel     matlab.ui.control.Label
        FiberwavelengthEditFieldLabel  matlab.ui.control.Label
        FiberwavelengthEditField       matlab.ui.control.NumericEditField
        NoisebandwidthEditFieldLabel   matlab.ui.control.Label
        NoisebandwidthEditField        matlab.ui.control.NumericEditField
        ReceiverResponsivityEditFieldLabel  matlab.ui.control.Label
        ReceiverResponsivityEditField  matlab.ui.control.NumericEditField
        ModulationIndexEditFieldLabel  matlab.ui.control.Label
        ModulationIndexEditField       matlab.ui.control.NumericEditField
        SignaltoNoiseRatiodBEditFieldLabel  matlab.ui.control.Label
        SignaltoNoiseRatiodBEditField  matlab.ui.control.NumericEditField
        Button                         matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        ReceivedPowerPrEditFieldLabel  matlab.ui.control.Label
        ReceivedPowerPrEditField       matlab.ui.control.NumericEditField
        ReceivedpowerindBmEditFieldLabel  matlab.ui.control.Label
        ReceivedpowerindBmEditField    matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            b= app.NoisebandwidthEditField.Value ;
            f= app.FiberwavelengthEditField.Value ;
            r= app.ReceiverResponsivityEditField.Value ;
            m= app.ModulationIndexEditField.Value ;
            s= app.SignaltoNoiseRatiodBEditField.Value ;
            p= (10^s/10)*4*(1.6*10^-19)*b/(m^2)*r ;
            app.ReceivedPowerPrEditField.Value = p ;
            d = 10* log(p) ;
            app.ReceivedpowerindBmEditField.Value = d ;
            plot(uiaxes,d,s,"Marker","diamond");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.OpticalReceiverDesignLabel = uilabel(app.UIFigure);
            app.OpticalReceiverDesignLabel.Text = {'Optical Receiver Design'; ''; ''};
            app.FiberwavelengthEditFieldLabel = uilabel(app.UIFigure);
            app.FiberwavelengthEditFieldLabel.Text = {'Fiber wavelength'; ''; ''};
            app.FiberwavelengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoisebandwidthEditFieldLabel = uilabel(app.UIFigure);
            app.NoisebandwidthEditFieldLabel.Text = {'Noise bandwidth'; ''};
            app.NoisebandwidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.ReceiverResponsivityEditFieldLabel = uilabel(app.UIFigure);
            app.ReceiverResponsivityEditFieldLabel.Text = {'Receiver Responsivity'; ''};
            app.ReceiverResponsivityEditField = uieditfield(app.UIFigure, 'numeric');
            app.ModulationIndexEditFieldLabel = uilabel(app.UIFigure);
            app.ModulationIndexEditFieldLabel.Text = {'Modulation Index'; ''};
            app.ModulationIndexEditField = uieditfield(app.UIFigure, 'numeric');
            app.SignaltoNoiseRatiodBEditFieldLabel = uilabel(app.UIFigure);
            app.SignaltoNoiseRatiodBEditFieldLabel.Text = {'Signal to Noise Ratio(dB)'; ''; ''};
            app.SignaltoNoiseRatiodBEditField = uieditfield(app.UIFigure, 'numeric');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {'optical Receiver design'; ''})
            xlabel(app.UIAxes, 'pr(dBm)')
            ylabel(app.UIAxes, {'Signal to Noise Ratio'; ''})
            app.ReceivedPowerPrEditFieldLabel = uilabel(app.UIFigure);
            app.ReceivedPowerPrEditFieldLabel.Text = {'Received Power(Pr)'; ''};
            app.ReceivedPowerPrEditField = uieditfield(app.UIFigure, 'numeric');
            app.ReceivedpowerindBmEditFieldLabel = uilabel(app.UIFigure);
            app.ReceivedpowerindBmEditFieldLabel.Text = {'Received power(in dBm)'; ''};
            app.ReceivedpowerindBmEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = optical_receiver_design

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end