classdef receiver_noise < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        NoiseTermsofOpticalReceiverLabel  matlab.ui.control.Label
        DraincurrentEditFieldLabel      matlab.ui.control.Label
        DraincurrentEditField           matlab.ui.control.NumericEditField
        EfficiencyEditFieldLabel        matlab.ui.control.Label
        EfficiencyEditField             matlab.ui.control.NumericEditField
        LoadResistanceEditFieldLabel    matlab.ui.control.Label
        LoadResistanceEditField         matlab.ui.control.NumericEditField
        ReceiverBandwidthEditFieldLabel  matlab.ui.control.Label
        ReceiverBandwidthEditField      matlab.ui.control.NumericEditField
        IncidentOpticalPowerEditFieldLabel  matlab.ui.control.Label
        IncidentOpticalPowerEditField   matlab.ui.control.NumericEditField
        RunButton                       matlab.ui.control.Button
        PrimaryPhotocurrentEditFieldLabel  matlab.ui.control.Label
        PrimaryPhotocurrentEditField    matlab.ui.control.NumericEditField
        ShotNoiseCurrentEditFieldLabel  matlab.ui.control.Label
        ShotNoiseCurrentEditField       matlab.ui.control.NumericEditField
        DarkCurrentEditFieldLabel       matlab.ui.control.Label
        DarkCurrentEditField            matlab.ui.control.NumericEditField
        ThermalNoiseCurrentEditFieldLabel  matlab.ui.control.Label
        ThermalNoiseCurrentEditField    matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            a=app.EfficiencyEditField.Value;
            p=app.IncidentOpticalPowerEditField.Value;
            c= a*(1.6*10^-19)*(1.3*10^-6)/(6.625*10^-34)*(3*10^8)*p ;
            app.PrimaryPhotocurrentEditField.Value= c ;
            b=app.ReceiverBandwidthEditField.Value ;
            s = 2*(1.6*10^-19)*c*b ;
            app.ShotNoiseCurrentEditField.Value = s ;
            d=app.DraincurrentEditField.Value ;
            f = 2*(1.6*10^-19)*d*b ;
            app.DarkCurrentEditField.Value = f ;
            r = app.LoadResistanceEditField.Value ;
            t= 4*(1.38*10^-23)*(293)/r*(b);
            app.ThermalNoiseCurrentEditField.Value = t ;
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.NoiseTermsofOpticalReceiverLabel = uilabel(app.UIFigure);
            app.NoiseTermsofOpticalReceiverLabel.Text = {'Noise Terms of Optical Receiver'; ''};
            app.DraincurrentEditFieldLabel = uilabel(app.UIFigure);
            app.DraincurrentEditFieldLabel.Text = {' Drain current'; ''};
            app.DraincurrentEditField = uieditfield(app.UIFigure, 'numeric');
            app.EfficiencyEditFieldLabel = uilabel(app.UIFigure);
            app.EfficiencyEditFieldLabel.Text = {'Efficiency'; ''};
            app.EfficiencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.LoadResistanceEditFieldLabel = uilabel(app.UIFigure);
            app.LoadResistanceEditFieldLabel.Text = 'Load Resistance';
            app.LoadResistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.ReceiverBandwidthEditFieldLabel = uilabel(app.UIFigure);
            app.ReceiverBandwidthEditFieldLabel.Text = 'Receiver Bandwidth';
            app.ReceiverBandwidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.IncidentOpticalPowerEditFieldLabel = uilabel(app.UIFigure);
            app.IncidentOpticalPowerEditFieldLabel.Text = {'Incident Optical Power'; ''};
            app.IncidentOpticalPowerEditField = uieditfield(app.UIFigure, 'numeric');
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.PrimaryPhotocurrentEditFieldLabel = uilabel(app.UIFigure);
            app.PrimaryPhotocurrentEditFieldLabel.Text = {'Primary Photo current'; ''};
            app.PrimaryPhotocurrentEditField = uieditfield(app.UIFigure, 'numeric');
            app.ShotNoiseCurrentEditFieldLabel = uilabel(app.UIFigure);
            app.ShotNoiseCurrentEditFieldLabel.Text = {'Shot Noise Current'; ''};
            app.ShotNoiseCurrentEditField = uieditfield(app.UIFigure, 'numeric');
            app.DarkCurrentEditFieldLabel = uilabel(app.UIFigure);
            app.DarkCurrentEditFieldLabel.Text = {'Dark Current'; ''};
            app.DarkCurrentEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThermalNoiseCurrentEditFieldLabel = uilabel(app.UIFigure);
            app.ThermalNoiseCurrentEditFieldLabel.Text = {'Thermal Noise Current'; ''};
            app.ThermalNoiseCurrentEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = receiver_noise

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end